classdef AplicacionBP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        KernelDropDown                  matlab.ui.control.DropDown
        SeleccionarKernelDropDownLabel  matlab.ui.control.Label
        AplicarKernelButton             matlab.ui.control.Button
        CargarConjuntodeKernelsButton   matlab.ui.control.Button
        CargarImagenButton              matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        ParaClasificacindeMariposasLabel  matlab.ui.control.Label
        AplicacindeunaRedBPLabel        matlab.ui.control.Label
        PredecirButton                  matlab.ui.control.Button
        PrediccinFinalLabel             matlab.ui.control.Label
        ImageAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imagenCargada
        imagenProcesada
        Kernels
    end
    
    methods (Access = private)
%% Función para Aplicar Kernel a la Imagen

        function result = aplicarKernel(~,img, kernel)
    [kh, kw] = size(kernel);
    [ih, iw] = size(img);
    
    padH = floor(kh/2);
    padW = floor(kw/2);
    
    imgPad = padarray(img, [padH padW], 'replicate');
    result = zeros(size(img));
    
    for i = 1:ih
        for j = 1:iw
            region = imgPad(i:i+kh-1, j:j+kw-1);
            result(i,j) = sum(sum(double(region) .* double(kernel)));
        end
    end
        end

%% Bloque de Funciones de Activación        


        function a = lineal(~, z)
            a = z;
        end

        function da = derivada_lineal(~, z)
            da = ones(size(z));
        end

        function a = sigmoide(~, z)
    a = 1 ./ (1 + exp(-z));
end

function da = derivada_sigmoide(~, a)
    da = a .* (1 - a); 
end

function out = softmax(~, x)
    exps = exp(x - max(x)); % Para evitar overflow numérico
    out = exps / sum(exps);
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredecirButton
        function PredecirButtonPushed2(app, event)
               if isempty(app.imagenCargada)
        uialert(app.UIFigure, 'Debe cargar una imagen primero.', 'Imagen no cargada');
        return;
    end

    if isempty(app.imagenProcesada)
    % Aplanar imagen cargada a vector columna
    input_vector = double(reshape(app.imagenCargada', [], 1));  % (4096x1)
    else
    input_vector = double(reshape(app.imagenProcesada', [], 1));  % (4096x1) 
    end
    
    input_vector = [input_vector; 1];  % Añadir el término de bias para poder hacer el aplanado.
    %disp(size(input_vector)); 

    % Normalizar entre 0 y 1
    input_vector = input_vector / 255;

    % Cargar modelo
    load('modelo_entrenado.mat', 'pesos', 'bias');
    W1 = pesos{1};
    W2 = pesos{2};
    b1 = bias{1};
    b2 = bias{2};

    %disp(size(W1));
    %disp(size(W2));

    % Forward pass
    z1 = W1' * input_vector + b1';
    a1 = app.sigmoide(z1);

    z2 = W2' * a1 + b2';
    a2 = app.sigmoide(z2);

porcentajes = round(a2 * 100, 2);
msg = sprintf('Monarca: %.2f%%\nIsabella: %.2f%%', 100 - porcentajes, porcentajes);
    %fprintf(msg);
    disp(a2);
    % Clasificación
    umbral = 0.60;
if a2 >= umbral
    idx = 1;
else
    idx = 2;
end

    especies = {'Isabella', 'Monarca'};
    
    clase_predicha = especies{idx};

    % Mostrar resultado
    app.PrediccinFinalLabel.Text = ['Predicción Final: ', clase_predicha];
        end

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
    clc;            
    [file, path] = uigetfile({'*.png;*.jpg;*.jpeg', 'Archivos de imagen'}, 'Seleccionar imagen');
    if isequal(file,0)
        return;
    end

    imgPath = fullfile(path, file);
    img = imread(imgPath);

    % Convertir a escala de grises si es necesario
    if size(img,3) == 3
        img = rgb2gray(img);
    end

    % Redimensionar a 64x64
    img = imresize(img, [64 64]);

    imshow(img, 'Parent', app.ImageAxes);
    % Guardar en propiedad
    app.imagenCargada = img;
        end

        % Button pushed function: CargarConjuntodeKernelsButton
        function CargarConjuntodeKernelsButtonPushed(app, event)
    fid = fopen('kernel.json');
    if fid == -1
        uialert(app.UIFigure, 'No se pudo cargar kernel.json', 'Error');
        return;
    end
    raw = fread(fid, inf);
    str = char(raw');
    fclose(fid);
    
    data = jsondecode(str);
    app.Kernels = data.kernels;
    
    nombres = string({app.Kernels.name});
    app.KernelDropDown.Items = nombres;
        end

        % Button pushed function: AplicarKernelButton
        function AplicarKernelButtonPushed(app, event)
        if isempty(app.imagenCargada)
        uialert(app.UIFigure, 'Primero cargue una imagen.', 'Advertencia');
        return;
    end
    
    nombre = app.KernelDropDown.Value;
    kernel = [];
    for i = 1:length(app.Kernels)
        if strcmp(app.Kernels(i).name, nombre)
            kernel = app.Kernels(i).matrix;
            break;
        end
    end
    
    % Convertir imagen a escala de grises
    img = app.imagenCargada;
    if size(img, 3) == 3
        img = rgb2gray(img);
    end
    kernel = app.Kernels(i).matrix;

% Si ya es un array, no es necesario hacer cell2mat
if iscell(kernel)
    kernel = double(cell2mat(kernel));
else
    kernel = double(kernel);
end

    % Normalización opcional para desenfoques
    if sum(kernel(:)) ~= 0 && contains(lower(nombre), "blur")
        kernel = kernel / sum(kernel(:));
    end

    % Aplicar convolución manual
    app.imagenProcesada = app.aplicarKernel(img, kernel);
    imshow(uint8(app.imagenProcesada), 'Parent', app.ImageAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Imagen Cargada')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.PrediccinFinalLabel = uilabel(app.UIFigure);
            app.PrediccinFinalLabel.Text = 'Predicción Final: ';
            app.PredecirButton = uibutton(app.UIFigure, 'push');
            app.PredecirButton.ButtonPushedFcn = createCallbackFcn(app, @PredecirButtonPushed2, true);
            app.PredecirButton.Text = 'Predecir';
            app.AplicacindeunaRedBPLabel = uilabel(app.UIFigure);
            app.AplicacindeunaRedBPLabel.Text = 'Aplicación de una Red BP';
            app.ParaClasificacindeMariposasLabel = uilabel(app.UIFigure);
            app.ParaClasificacindeMariposasLabel.Text = 'Para Clasificación de Mariposas';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Hecho Por: Santiago Naranjo Herrera, Oscar Felipe Valcárcel, Daniel Steven Hincapié';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_universidad.png');
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.CargarConjuntodeKernelsButton = uibutton(app.UIFigure, 'push');
            app.CargarConjuntodeKernelsButton.ButtonPushedFcn = createCallbackFcn(app, @CargarConjuntodeKernelsButtonPushed, true);
            app.CargarConjuntodeKernelsButton.Text = 'Cargar Conjunto de Kernels';
            app.AplicarKernelButton = uibutton(app.UIFigure, 'push');
            app.AplicarKernelButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarKernelButtonPushed, true);
            app.AplicarKernelButton.Text = 'Aplicar Kernel';
            app.SeleccionarKernelDropDownLabel = uilabel(app.UIFigure);
            app.SeleccionarKernelDropDownLabel.Text = 'Seleccionar Kernel';
            app.KernelDropDown = uidropdown(app.UIFigure);
            app.KernelDropDown.Items = {'Carga Conjunto de Kernels'};
            app.KernelDropDown.Value = 'Carga Conjunto de Kernels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AplicacionBP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end