classdef EntrenamientoU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MomentumEdit                 matlab.ui.control.NumericEditField
        MomentumEditFieldLabel       matlab.ui.control.Label
        EpocaActual                  matlab.ui.control.Label
        MSEObjetivoEdit              matlab.ui.control.NumericEditField
        ErrorObjetivoEditFieldLabel  matlab.ui.control.Label
        LearningRateEdit             matlab.ui.control.NumericEditField
        TasadeAprendizajeEditFieldLabel  matlab.ui.control.Label
        NeuronasOcultasEdit          matlab.ui.control.NumericEditField
        NumerodeNeuronasenlaCapaOcultaEditFieldLabel  matlab.ui.control.Label
        CargarYdButton               matlab.ui.control.Button
        CargarXButton                matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        Label                        matlab.ui.control.Label
        ParaClasificacindeMariposasLabel  matlab.ui.control.Label
        EntrenamientodeRedBPLabel    matlab.ui.control.Label
        ErrorFinalLabel              matlab.ui.control.Label
        IniciarEntrenamientoButton   matlab.ui.control.Button
        Axes2                        matlab.ui.control.UIAxes
        Axes1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X   % Matriz de entrada
        Yd  % Matriz de salida deseada
        pesos_entrada_oculta
        pesos_salida_oculta
        bias_oculta
        bias_salida
        mse_final
    end
    
    methods (Access = private)

        function EntrenarRed(app)
    % Parámetros de red
    entradas = size(app.X, 2);
    patrones = size(app.X, 1);
    salidas = size(app.Yd, 2);
    neuronas_ocultas = app.NeuronasOcultasEdit.Value;
    tasa_aprendizaje = app.LearningRateEdit.Value;
    mse_objetivo = app.MSEObjetivoEdit.Value;
    momentum = app.MomentumEdit.Value;

    % Inicializar pesos y biases pequeños aleatorios
    rng('shuffle'); % Semilla aleatoria
    app.pesos_entrada_oculta = randn(entradas, neuronas_ocultas) * 0.1;
    app.bias_oculta = randn(1, neuronas_ocultas) * 0.1;
    app.pesos_salida_oculta = randn(neuronas_ocultas, salidas) * 0.1;
    app.bias_salida = randn(1, salidas) * 0.1;

    % Inicializar deltas anteriores para momentum
    delta_W1_ant = zeros(size(app.pesos_entrada_oculta));
    delta_b1_ant = zeros(size(app.bias_oculta));
    delta_W2_ant = zeros(size(app.pesos_salida_oculta));
    delta_b2_ant = zeros(size(app.bias_salida));

    epoca = 0;
    mse = inf;

    mse_historia = zeros(1, 1000);  % Ajusta el tamaño si necesitas más épocas
    out_yo_total = zeros(patrones, salidas);

    while mse > mse_objetivo
        mse = 0;
        for p = 1:patrones
            xp = app.X(p, :);
            yd = app.Yd(p, :);

            % Forward Pass
            net_h = xp * app.pesos_entrada_oculta + app.bias_oculta;
            out_h = app.sigmoide(net_h);

            net_o = out_h * app.pesos_salida_oculta + app.bias_salida;
            out_o = app.sigmoide(net_o);
            out_yo_total(p, :) = out_o;

            % Error
            error = yd - out_o;
            mse = mse + sum(error.^2);

            % Backpropagation
            delta_o = error .* app.derivada_sigmoide(out_o);
            delta_h = (delta_o * app.pesos_salida_oculta') .* app.derivada_sigmoide(out_h);

            % Cálculo del cambio (delta) de pesos y bias
            delta_W2 = tasa_aprendizaje * (out_h') * delta_o;
            delta_b2 = tasa_aprendizaje * delta_o;
            delta_W1 = tasa_aprendizaje * (xp') * delta_h;
            delta_b1 = tasa_aprendizaje * delta_h;

% En vez de modificar delta_W* en el mismo lugar, crea variables aparte:
if momentum ~= 0
    delta_W2_total = delta_W2 + momentum * delta_W2_ant;
    delta_b2_total = delta_b2 + momentum * delta_b2_ant;
    delta_W1_total = delta_W1 + momentum * delta_W1_ant;
    delta_b1_total = delta_b1 + momentum * delta_b1_ant;
else
    delta_W2_total = delta_W2;
    delta_b2_total = delta_b2;
    delta_W1_total = delta_W1;
    delta_b1_total = delta_b1;
end

% Luego usa solo las variables *_total para actualizar
app.pesos_salida_oculta = app.pesos_salida_oculta + delta_W2_total;
app.bias_salida = app.bias_salida + delta_b2_total;
app.pesos_entrada_oculta = app.pesos_entrada_oculta + delta_W1_total;
app.bias_oculta = app.bias_oculta + delta_b1_total;

            % Guardar los deltas actuales como anteriores para la siguiente iteración
            delta_W2_ant = delta_W2;
            delta_b2_ant = delta_b2;
            delta_W1_ant = delta_W1;
            delta_b1_ant = delta_b1;
        end

        mse = mse / patrones;
        epoca = epoca + 1;
        app.EpocaActual.Text = ['Epoca Actual: ', num2str(epoca)];
        mse_historia(epoca) = mse;

        % Graficar MSE
        plot(app.Axes1, 1:epoca, mse_historia(1:epoca), '-o');
        title(app.Axes1, 'MSE durante el entrenamiento');
        xlabel(app.Axes1, 'Épocas');
        ylabel(app.Axes1, 'MSE');
        app.ErrorFinalLabel.Text = ['Error Actual: ', num2str(mse)];
        drawnow;

% Graficar Yd vs Y Obtenida al finalizar el entrenamiento
plot(app.Axes2, app.Yd, 'ro', 'DisplayName', 'Yd');
hold(app.Axes2, 'on');
plot(app.Axes2, out_yo_total, 'b*', 'DisplayName', 'Y Obtenida');
hold(app.Axes2, 'off');
legend(app.Axes2, 'Location', 'best');
title(app.Axes2, 'Yd vs Y Obtenida');
xlabel(app.Axes2, 'Patrón');
ylabel(app.Axes2, 'Salida');

    end

    app.mse_final = mse;
    app.ErrorFinalLabel.Text = ['Error Final: ', num2str(mse)];

    % Guardar el modelo
    pesos = {app.pesos_entrada_oculta, app.pesos_salida_oculta};
    bias = {app.bias_oculta, app.bias_salida};
    save('modelo_entrenado.mat', 'pesos', 'bias');

    disp(table(app.Yd, out_yo_total));
        end

                 function a = lineal(~, z)
            a = z;
        end

        function da = derivada_lineal(~, z)
            da = ones(size(z));
        end

        function a = sigmoide(~, z)
    a = 1 ./ (1 + exp(-z));
end

function da = derivada_sigmoide(~, a)
    da = a .* (1 - a); 
end

                        
         end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarEntrenamientoButton
        function IniciarEntrenamientoButtonPushed(app, event)
            if isempty(app.X) || isempty(app.Yd)
                uialert(app.UIFigure, 'Carga X y Yd primero.', 'Error');
                return;
            end
            EntrenarRed(app);
        end

        % Button pushed function: CargarXButton
        function CargarXButtonPushed(app, event)
                        [archivo, ruta] = uigetfile('*.txt', 'Selecciona archivo X');
            if isequal(archivo,0)
                return;
            end
            datos = load(fullfile(ruta, archivo));
            app.X = datos;
        end

        % Button pushed function: CargarYdButton
        function CargarYdButtonPushed(app, event)
                        [archivo, ruta] = uigetfile('*.txt', 'Selecciona archivo Yd');
            if isequal(archivo,0)
                return;
            end
            datos = load(fullfile(ruta, archivo));
            app.Yd = datos;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axes1 = uiaxes(app.UIFigure);
            title(app.Axes1, 'Epocas Vs MSE')
            xlabel(app.Axes1, 'Épocas')
            ylabel(app.Axes1, 'MSE')
            zlabel(app.Axes1, 'Z')
            app.Axes2 = uiaxes(app.UIFigure);
            title(app.Axes2, 'Yd vs Y Obtenida')
            xlabel(app.Axes2, 'Y Deseada')
            ylabel(app.Axes2, 'Y Obtenida')
            zlabel(app.Axes2, 'Z')
            app.IniciarEntrenamientoButton = uibutton(app.UIFigure, 'push');
            app.IniciarEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarEntrenamientoButtonPushed, true);
            app.IniciarEntrenamientoButton.Text = 'Iniciar Entrenamiento';
            app.ErrorFinalLabel = uilabel(app.UIFigure);
            app.ErrorFinalLabel.Text = 'Error Actual: 0.0';
            app.EntrenamientodeRedBPLabel = uilabel(app.UIFigure);
            app.EntrenamientodeRedBPLabel.Text = 'Entrenamiento de Red BP';
            app.ParaClasificacindeMariposasLabel = uilabel(app.UIFigure);
            app.ParaClasificacindeMariposasLabel.Text = 'Para Clasificación de Mariposas';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Hecho Por: Santiago Naranjo Herrera, Oscar Felipe Valcárcel, Daniel Steven Hincapié';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_universidad.png');
            app.CargarXButton = uibutton(app.UIFigure, 'push');
            app.CargarXButton.ButtonPushedFcn = createCallbackFcn(app, @CargarXButtonPushed, true);
            app.CargarXButton.Text = 'Cargar X';
            app.CargarYdButton = uibutton(app.UIFigure, 'push');
            app.CargarYdButton.ButtonPushedFcn = createCallbackFcn(app, @CargarYdButtonPushed, true);
            app.CargarYdButton.Text = 'Cargar Yd';
            app.NumerodeNeuronasenlaCapaOcultaEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodeNeuronasenlaCapaOcultaEditFieldLabel.Text = 'Numero de Neuronas en la Capa Oculta';
            app.NeuronasOcultasEdit = uieditfield(app.UIFigure, 'numeric');
            app.TasadeAprendizajeEditFieldLabel = uilabel(app.UIFigure);
            app.TasadeAprendizajeEditFieldLabel.Text = 'Tasa de Aprendizaje';
            app.LearningRateEdit = uieditfield(app.UIFigure, 'numeric');
            app.ErrorObjetivoEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorObjetivoEditFieldLabel.Text = 'Error Objetivo';
            app.MSEObjetivoEdit = uieditfield(app.UIFigure, 'numeric');
            app.EpocaActual = uilabel(app.UIFigure);
            app.EpocaActual.Text = 'Época Actual: 0';
            app.MomentumEditFieldLabel = uilabel(app.UIFigure);
            app.MomentumEditFieldLabel.Text = 'Momentum';
            app.MomentumEdit = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EntrenamientoU

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end