classdef ProcesamientoKernels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RestableceraImagenOriginalButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        AplicacindeProcesamientodeImgenesLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ProcesarDatasetdeImgenesButton  matlab.ui.control.Button
        CargarConjuntodeKernelsButton   matlab.ui.control.Button
        AplicarFiltroButton             matlab.ui.control.Button
        KernelDropDown                  matlab.ui.control.DropDown
        SeleccionarKernelDropDownLabel  matlab.ui.control.Label
        CargarImagenButton              matlab.ui.control.Button
        UIAxes_Procesada                matlab.ui.control.UIAxes
        UIAxes_Original                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    originalImage
    processedImage
    Kernels
    end
    
    methods (Access = private)

        function result = aplicarKernel(~,img, kernel)
    [kh, kw] = size(kernel);
    [ih, iw] = size(img);
    
    padH = floor(kh/2);
    padW = floor(kw/2);
    
    imgPad = padarray(img, [padH padW], 'replicate');
    result = zeros(size(img));
    
    for i = 1:ih
        for j = 1:iw
            region = imgPad(i:i+kh-1, j:j+kw-1);
            result(i,j) = sum(sum(double(region) .* double(kernel)));
        end
    end
        end

        %% Funciones Para Filtros de Color
function imgBlanco = blanco(~,img)
    imgBlanco = rgb2gray(img); % Convertir a escala de grises
end

function imgAzul = azul(~,img)
    imgAzul = img;
    imgAzul(:,:,1) = 0; % Eliminar el canal Rojo
    imgAzul(:,:,2) = 0; % Eliminar el canal Verde
end

function imgVerde = verde(~,img)
    imgVerde = img;
    imgVerde(:,:,1) = 0; % Eliminar el canal Rojo
    imgVerde(:,:,3) = 0; % Eliminar el canal Azul
end

function imgRojo = rojo(~,img)
    imgRojo = img;
    imgRojo(:,:,2) = 0; % Eliminar el canal Verde
    imgRojo(:,:,3) = 0; % Eliminar el canal Azul
end

function imgEscalaColores = escalaColores(~,img)
    imgEscalaColores = rgb2gray(img); % Convertir a escala de grises
    imgEscalaColores = cat(3, imgEscalaColores, imgEscalaColores, imgEscalaColores); % Reconvertir a RGB
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarConjuntodeKernelsButton
        function CargarConjuntodeKernelsButtonPushed(app, event)
                fid = fopen('kernel.json');
    if fid == -1
        uialert(app.UIFigure, 'No se pudo cargar kernel.json', 'Error');
        return;
    end
    raw = fread(fid, inf);
    str = char(raw');
    fclose(fid);
    
    data = jsondecode(str);
    app.Kernels = data.kernels;
    
    nombres = string({app.Kernels.name});
    app.KernelDropDown.Items = nombres;
        end

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
                [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Imágenes (*.jpg, *.png, *.bmp)'});
    if isequal(file, 0)
        return;
    end
    ruta = fullfile(path, file);
    app.originalImage = imread(ruta);
    imshow(app.originalImage, 'Parent', app.UIAxes_Original);
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
                if isempty(app.originalImage)
        uialert(app.UIFigure, 'Primero cargue una imagen.', 'Advertencia');
        return;
    end
    
    nombre = app.KernelDropDown.Value;
    kernel = [];
    for i = 1:length(app.Kernels)
        if strcmp(app.Kernels(i).name, nombre)
            kernel = app.Kernels(i).matrix;
            break;
        end
    end
    
    % Convertir imagen a escala de grises
    if isempty(app.processedImage)
    img = app.originalImage;
else
    img = app.processedImage;
end
    if size(img, 3) == 3
        img = rgb2gray(img);
    end
    kernel = app.Kernels(i).matrix;

% Si ya es un array, no es necesario hacer cell2mat
if iscell(kernel)
    kernel = double(cell2mat(kernel));
else
    kernel = double(kernel);
end

    % Normalización opcional para desenfoques
    if sum(kernel(:)) ~= 0 && contains(lower(nombre), "blur")
        kernel = kernel / sum(kernel(:));
    end

    % Aplicar convolución manual
    app.processedImage = app.aplicarKernel(img, kernel);
    imshow(uint8(app.processedImage), 'Parent', app.UIAxes_Procesada);
        end

        % Callback function
        function GuardarVectorButtonPushed(app, event)
                if isempty(app.processedImage)
        uialert(app.UIFigure, 'No hay imagen procesada para guardar.', 'Advertencia');
        return;
    end

    vec = double(reshape(app.processedImage, 1, [])) / 255.0;
    
    [file, path] = uiputfile('vector.csv');
    if isequal(file, 0)
        return;
    end

    fid = fopen(fullfile(path, file), 'w');
    fprintf(fid, '%f,', vec(1:end-1));
    fprintf(fid, '%f\n', vec(end));
    fclose(fid);

    uialert(app.UIFigure, 'Vector guardado exitosamente.', 'Éxito');
        end

        % Callback function: not associated with a component
        function AplicarFiltrodeColorButtonPushed(app, event)
                if isempty(app.originalImage)
        uialert(app.UIFigure, 'Primero cargue una imagen.', 'Advertencia');
        return;
    end
    
    % Seleccionar el filtro de color
    colorFilter = listdlg('PromptString', 'Seleccione el filtro de color:', ...
                          'SelectionMode', 'single', ...
                          'ListString', {'Blanco', 'Azul', 'Verde', 'Rojo', 'Escala de Colores'});

    % Comprobar si se seleccionó un filtro
    if isempty(colorFilter)
        return; % Si el usuario cancela
    end
    
    % Aplicar el filtro seleccionado
    switch colorFilter
        case 1 % Blanco
            app.processedImage = app.blanco(app.originalImage);
        case 2 % Azul
            app.processedImage = app.azul(app.originalImage);
        case 3 % Verde
            app.processedImage = app.verde(app.originalImage);
        case 4 % Rojo
            app.processedImage = app.rojo(app.originalImage);
        case 5 % Escala de Colores
            app.processedImage = app.escalaColores(app.originalImage);
    end
    
    % Mostrar la imagen procesada
    imshow(app.processedImage, 'Parent', app.UIAxes_Procesada);
        end

        % Button pushed function: ProcesarDatasetdeImgenesButton
        function ProcesarDatasetdeImgenesButtonPushed(app, event)
% Seleccionar carpeta
carpeta = uigetdir();
if carpeta == 0
    return;
end

% Obtener lista de imágenes
imagenes = dir(fullfile(carpeta, '*.jpg'));
imagenes = [imagenes; dir(fullfile(carpeta, '*.png'))];
imagenes = [imagenes; dir(fullfile(carpeta, '*.bmp'))];

if isempty(imagenes)
    uialert(app.UIFigure, 'No se encontraron imágenes en la carpeta.', 'Advertencia');
    return;
end

% Seleccionar kernel
nombre = app.KernelDropDown.Value;
kernel = [];
for i = 1:length(app.Kernels)
    if strcmp(app.Kernels(i).name, nombre)
        kernel = app.Kernels(i).matrix;
        break;
    end
end

if isempty(kernel)
    uialert(app.UIFigure, 'No se encontró el kernel seleccionado.', 'Error');
    return;
end

if iscell(kernel)
    kernel = double(cell2mat(kernel));
else
    kernel = double(kernel);
end

% Normalizar kernel si es blur
if sum(kernel(:)) ~= 0 && contains(lower(nombre), "blur")
    kernel = kernel / sum(kernel(:));
end

% Preparar matriz para guardar vectores
vectores = [];
etiquetas = [];

% Procesar cada imagen
tamanoFijo = [64, 64]; % Tamaño estándar
for idx = 1:length(imagenes)
    ruta = fullfile(carpeta, imagenes(idx).name);
    img = imread(ruta);

    % Convertir a escala de grises si es RGB
    if size(img, 3) == 3
        img = rgb2gray(img);
    end

    % Redimensionar
    img = imresize(img, tamanoFijo);

    % Convertir a tipo double y normalizar a [0, 1]
    img = double(img) / 255.0;

    % Aplicar kernel (tu función debe aceptar imágenes en double normalizadas)
    imgProcesada = app.aplicarKernel(img, kernel);

    % Reescalar la imagen procesada a [0, 1] para RNA
    imgProcesada = imgProcesada - min(imgProcesada(:));
    imgProcesada = imgProcesada / max(imgProcesada(:));

    % Vectorizar
    vec = reshape(imgProcesada, 1, []);

    vectores = [vectores; vec]; % Acumular vectores
    etiquetas = [etiquetas; 1]; % Etiqueta fija, cámbiala si tienes múltiples clases
end

% Combinar vectores + etiquetas
datosFinales = [vectores etiquetas];

% Guardar archivo
[file, path] = uiputfile('datos.txt');
if isequal(file, 0)
    return;
end

writematrix(datosFinales, fullfile(path, file), 'Delimiter', 'tab');
uialert(app.UIFigure, 'Procesamiento de carpeta completado.', 'Éxito');
        end

        % Button pushed function: RestableceraImagenOriginalButton
        function RestableceraImagenOriginalButtonPushed(app, event)
                if isempty(app.originalImage)
        uialert(app.UIFigure, 'No se ha cargado una imagen.', 'Advertencia');
        return;
    end
    app.processedImage = []; % Borrar procesamiento acumulado
    imshow(app.originalImage, 'Parent', app.UIAxes_Procesada);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Original = uiaxes(app.UIFigure);
            title(app.UIAxes_Original, 'Imagen Original')
            app.UIAxes_Original.XTick = [];
            app.UIAxes_Original.YTick = [];
            app.UIAxes_Procesada = uiaxes(app.UIFigure);
            title(app.UIAxes_Procesada, 'Imagen Procesada')
            app.UIAxes_Procesada.XTick = [];
            app.UIAxes_Procesada.YTick = [];
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.SeleccionarKernelDropDownLabel = uilabel(app.UIFigure);
            app.SeleccionarKernelDropDownLabel.Text = 'Seleccionar Kernel';
            app.KernelDropDown = uidropdown(app.UIFigure);
            app.KernelDropDown.Items = {'Carga Conjunto de Kernels'};
            app.KernelDropDown.Value = 'Carga Conjunto de Kernels';
            app.AplicarFiltroButton = uibutton(app.UIFigure, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar Filtro';
            app.CargarConjuntodeKernelsButton = uibutton(app.UIFigure, 'push');
            app.CargarConjuntodeKernelsButton.ButtonPushedFcn = createCallbackFcn(app, @CargarConjuntodeKernelsButtonPushed, true);
            app.CargarConjuntodeKernelsButton.Text = 'Cargar Conjunto de Kernels';
            app.ProcesarDatasetdeImgenesButton = uibutton(app.UIFigure, 'push');
            app.ProcesarDatasetdeImgenesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarDatasetdeImgenesButtonPushed, true);
            app.ProcesarDatasetdeImgenesButton.Text = 'Procesar Dataset de Imágenes';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_universidad.png');
            app.AplicacindeProcesamientodeImgenesLabel = uilabel(app.UIFigure);
            app.AplicacindeProcesamientodeImgenesLabel.Text = 'Aplicación de Procesamiento de Imágenes';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Por: Santiago Naranjo Herrera, Oscar Felipe Valcárcel Peralta y Daniel Steven Hincapié Cetina';
            app.RestableceraImagenOriginalButton = uibutton(app.UIFigure, 'push');
            app.RestableceraImagenOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @RestableceraImagenOriginalButtonPushed, true);
            app.RestableceraImagenOriginalButton.Text = 'Restablecer a Imagen Original';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcesamientoKernels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end